classdef Auraliza < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ViewType                        matlab.ui.control.Switch
        CAMHeadTrackerButton            matlab.ui.control.Button
        BufferSize                      matlab.ui.control.NumericEditField
        BufferSizeLabel                 matlab.ui.control.Label
        InterpolationDropDown           matlab.ui.control.DropDown
        InterpolationDropDownLabel      matlab.ui.control.Label
        PauseButton                     matlab.ui.control.StateButton
        PlayButton                      matlab.ui.control.StateButton
        BinauralRenderButton            matlab.ui.control.StateButton
        AirabsorptionCheckBox           matlab.ui.control.CheckBox
        DistanceSlider                  matlab.ui.control.Slider
        DIstancemLabel                  matlab.ui.control.Label
        OptionalInputsPanel             matlab.ui.container.Panel
        XButton                         matlab.ui.control.Button
        HpTFWAVButton                   matlab.ui.control.StateButton
        opt_IRpath                      matlab.ui.control.EditField
        InputsPanel                     matlab.ui.container.Panel
        AudioWAVButton                  matlab.ui.control.StateButton
        HRTFSOFAButton                  matlab.ui.control.StateButton
        AUDIOpath                       matlab.ui.control.EditField
        SOFApath                        matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        AdvancedTab                     matlab.ui.container.Tab
        DistancevariationmethodListBox  matlab.ui.control.ListBox
        DistancevariationmethodListBoxLabel  matlab.ui.control.Label
        AirabsorptionpropertiesISO96131Panel  matlab.ui.container.Panel
        airPressure                     matlab.ui.control.NumericEditField
        AtmosphericpressureratioCurrentStandadEditFieldLabel  matlab.ui.control.Label
        airHumidity                     matlab.ui.control.NumericEditField
        HumidityLabel                   matlab.ui.control.Label
        airTemperature                  matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel      matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        ContactLabel                    matlab.ui.control.Label
        davicarvalhoeacufsmbrLabel      matlab.ui.control.Label
        DaviRochaCarvalhoEngAcsticaUFSMLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        Pax           % Polar axes
        Obj_HRTF      % SOFA HRTF file
        posi          % posições de fonte 
        fs_SOFA       % sample rate SOFA
        fs_Audio      % sample rate Audio 
        Audio         % Audio file input
        deviceWriter  % dispositivo de reprodução
        dspHRIR_L     % HRIR esquerda para a posição selecionada
        dspHRIR_R     % HRIR esquerda para a posição selecionada
        NameAudio     % nome no Audio sem extensão
        Audiofolder   % folder path 
        opt_IR        % input IR opcional
        fs_opt_IR     % sample rate da IR opcional
        no_input      % numero de canais de entrada 
        flag_opt_IR= 0;   % avisa se ha input
        ROI           % região de interesse (posição da fonte)
        ROI_flag=1;     % avisa se ROI foi inicializado ou não
        roi_listen    % roi listener 
        convAudio     % audio convoluido com resposta impulsiva
        flag_interp='';   % flag de erro de interpolação
        ROI_list      % lista com nome dos ROI
        headradius    % Raio da cabeça do sujeito 
        samplingGrid  % Posições SOFA originais
        DistNorm     % Amplitude normalization for distance variation
        startUP = 1;      % flag pra carregar arquivos default
        DistValue     % valor da distancia atribuida pelo slider
        FIR           % Alocated FIR filters 
        idx_FIR       % Index do filtro correspondente a posição atual 
        bkp_FIR       % save FIR when not in use, avoid ifs in the main loop
        MarkerSize    % Tamanho da bolinha do ROI
        air_abs       % coeficiente de absorção do ar
        N             % 1/2 do número de samples da HRIR
        flag_DistChanged   % flag pra indicar se distancia foi variada ou não
        flag_HeadTracker = 0;  % flag pra indicar se HT está ligado 
        udpr           % UDP connection to the head tracker
        props         % plot properties
        flag_airabs_eval  % indica se calculo da absorcao precisa ser atualizado
        parallax_pos_L  % parallaxed new grid (left)
        parallax_pos_R  % parallaxed new grid (right)
        conv_done= false; %flag para indicar se HpTF ja foi aplicada ou nao 
        norm_freq       % normalizd frequency vector 
        AirAbsFilter    % air absorption filter
        abs_dist_vec    % pre calculated distances for the air absorption 
        dist_FIR        % FIR filter for distance variation via HRTF equalization
        radius          % Source position radius for each source radius.(name)
    end
    
        
    methods (Access = private)                          
        function initializeROI(app, azimm, elevv)
        if nargin < 3 || isempty(azimm)
             [azimm, elevv] = n_sources_coord(app.no_input);
             disp('estimating positions')
        end
        app.ROI_list = cell(app.no_input,1);
        for k = 1:app.no_input                      
            roi_name = (['CH' num2str(k)]);
            app.ROI_list{k} = roi_name;  
            
            %%% Desenhar ROI objeto %%%
            if app.ROI_flag % evita reinicializar o ROI e outras coisas a cada play               
                get_parallax_pos(app)  % inicializar parallax
                
                % (ROI coordinates change according to view)
                switch app.ViewType.Value
                    case 'Frontal'  % (ROI : azimuth / elevation)
                        y_coord = elevv(k);
                        x_coord = azimm(k);
                        app.radius.(roi_name) = app.DistValue; % case change the view later
                        markersize(app);
                    case 'Top' % (ROI : azimuth / radius)
                        try 
                        	 y_coord = app.radius.(roi_name);
                        catch
                             y_coord = app.DistValue;
                             app.radius.(roi_name) = y_coord;
                        end
                        [x_coord, y_coord,~] = sph2cart(deg2rad(azimm(k)),...
                                                  0,...
                                                  y_coord);
                        app.MarkerSize = 7;
                end
                
                % actually draw the ROI
                if verLessThan('matlab','9.9') % markersize(num existia antes do R2020b)
                    app.ROI.(roi_name) = drawpoint(app.UIAxes, 'Position',[x_coord, y_coord],...
                                           'color', 'y');
                else
                    app.ROI.(roi_name) = drawpoint(app.UIAxes, 'Position',[x_coord, y_coord],...
                                           'color', 'y', 'MarkerSize', app.MarkerSize);
                end
                
                % initialize hrtf variables and values
                app.ROI.(roi_name).Tag = roi_name; % Valor interno 
                HRIR_filter_design(app, azimm(k), elevv(k), roi_name); % view independent  
                app.roi_listen = addlistener(app.ROI.(roi_name), 'MovingROI', @app.allevents);
                app.ROI.(roi_name).Label = roi_name; % Valor visível                            
            end
        end
        set(app.UIFigure,'doublebuffer','off');
        app.ROI_flag = 0;   % Indica que já foi desenhado             
        end
            
        function allevents(app, src, ~)
            pos = src.Position;
            switch app.ViewType.Value 
                case 'Top'
                    pos = src.Position;
                    [azimuth,~,radi]=cart2sph(pos(1), pos(2), 0);   
                    azimuth = rad2deg(azimuth);
                    elevation = 0;              
                    app.radius.(src.Tag) = radi;
                    app.DistValue = radi;
                    app.flag_DistChanged = 1;                 
                otherwise                    
                    azimuth = pos(1);
                    elevation = pos(2);
            end
            HRIR_filter_design(app, azimuth, elevation, src.Tag)
        end
        
        function [azim, elev, radius] = top2cart_coords(~, x, y )
            [azim,~,radius]=cart2sph(x, y, 0);                    
            azim = rad2deg(azim);
            elev = 0;                            
        end
        function get_parallax_pos(app)         
            if app.DistValue < app.posi(1,3)
                [app.parallax_pos_L, app.parallax_pos_R]=...
                                    hrtf_parallax(app.samplingGrid,...
                                                  app.DistValue,...
                                                  app.headradius); 
            else
                app.parallax_pos_L = app.samplingGrid;
                app.parallax_pos_R = app.samplingGrid;
            end
        end 
        
        function markersize(app)
                app.MarkerSize = app.headradius/(app.DistValue)*100;
            if  app.MarkerSize < 7               
                app.MarkerSize = 7;
            end
        end        
        
        %% DRAW FRONT VIEW %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function draw_front_view(app, azimm, elevv)
            if nargin < 2
                azimm = [];
                elevv = [];
            end
            hold(app.UIAxes, 'off');  cla(app.UIAxes)
            axis(app.UIAxes, 'normal')
            x=app.posi(:,1); y=app.posi(:,2);
            line(app.UIAxes,[x,x],[y,y],app.props{:}); 
            hold(app.UIAxes, 'on');  
            view(app.UIAxes, [0 90])
            set(app.UIAxes,'XColor', [.4 .4 .4],'YColor', [.4 .4 .4])
            %> Initialize ROI
            initializeROI(app, azimm, elevv)          
        end
        
        %% DRAW TOP VIEW %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function draw_top_view(app, azimm, elevv)
            if nargin < 2
                azimm = [];
                elevv = [];
            end
            hold(app.UIAxes, 'off');  cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            r = [app.headradius, 0.25, 0.50, 0.75, 1, 1.5, 2, 3, 4];
            circles(app,r);          
            axis(app.UIAxes, 'tight')
            axis(app.UIAxes, 'square') 
            view(app.UIAxes, [90 -90])
            set(app.UIAxes,'XColor', 'none','YColor','none')

            %> Initialize ROI
            initializeROI(app, azimm, elevv )
        end
        
        function circles(app, r)            
            x = 0;
            y = 0;        
            th = 0:pi/50:2*pi; 
            for k = 1:length(r)
                xunit = r(k) * cos(th) + x;
                yunit = r(k)* sin(th) + y;
                color = [.5 .5 .5];
                if k == 1
                    color = [1 0 0];
                end
                plot(app.UIAxes, xunit, yunit,...
                    'linewidth', 1.5, 'color', color);                
            end
                        
            % ear positions
%             ear_l = 90;
%             ear_r = 270;
%             nose = 0;
%             lr = deg2rad([ear_l; ear_r; nose]);
%             elev = 0;
%             [xl, yl, ~] = sph2cart(lr(1), elev, r(1));
%             [xr, yr, ~] = sph2cart(lr(2), elev, r(1));
%             [xn, yn, ~] = sph2cart(lr(3), elev, r(1));
% %             
%             scatter(app.UIAxes, xl, yl, 20,'r', '*');  
%             scatter(app.UIAxes, xr, yr, 20,'r','*')
%             scatter(app.UIAxes, xn+0.01, yn, 20,'>', 'filled', 'color', [.4 .4 .4])
            
         end
        
        
        %% FIR FILTERS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function allocateFIRfilter(app)
            for k = 1:app.no_input*2
                % app.FIR{k} = dsp.FIRFilter();   
                app.FIR{k} =  dsp.FrequencyDomainFIRFilter('Method',...
                               'overlap-save',...
                              'PartitionForReducedLatency', true,...
                              'PartitionLength', round(app.N/2));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,'center')
            % Desabilitar iteratividade do plot
            disableDefaultInteractivity(app.UIAxes); 
            app.UIAxes.Toolbar.Visible = 'off'; 
            set(app.UIAxes,'XColor',[0.5 0.5 0.5]); % tick label colors
            set(app.UIAxes,'YColor',[0.5 0.5 0.5]); % tick label colors
            set(app.UIAxes, 'FontSize', 10)

            AudioWAVButtonValueChanged(app,app.startUP);
            HRTFSOFAButtonValueChanged(app,app.startUP);           
        end

        % Value changed function: HRTFSOFAButton
        function HRTFSOFAButtonValueChanged(app, event)
            if app.startUP
                baseName = 'individuo_141.sofa';
               app.Obj_HRTF = SOFAload(baseName, 'nochecks');
               app.SOFApath.Value = baseName; 
               app.startUP = 0;
            else % LOAD SOFA
                [baseName, folder] = uigetfile([pwd, filesep, '*.sofa'], 'Load SOFA HRTF:');
                app.SOFApath.Value = baseName;  
                figure(app.UIFigure) %keep focus on app window
                app.Obj_HRTF = SOFAload(fullfile(folder, baseName), 'nochecks');
            end
            %%% Properties
            if length(app.Obj_HRTF.Data.Delay) ~= length(app.Obj_HRTF.SourcePosition)
                [~, app.Obj_HRTF] = SOFAgetITD(app.Obj_HRTF,'samples');% save ITD in Obj.Data.Delay
            end
            app.posi = app.Obj_HRTF.SourcePosition;
             % Convert to supdeq parallax coordinates
            app.samplingGrid = app.posi(:,1:2);
            app.headradius = app.Obj_HRTF.ReceiverPosition(1, 2); % raio da cabeça
            if app.headradius >= 0.25
                app.headradius = app.headradius/10;
            end
            app.DistanceSlider.Value = app.posi(1,3);
            app.DistanceSlider.Limits(1) = app.headradius+0.1;
            app.DistValue = app.DistanceSlider.Value;
            % convert coordinates for ploting
            [app.posi(:,1), app.posi(:,2)] = sph2nav(app.posi(:,1),app.posi(:,2));
            app.posi(:,1) = - app.posi(:,1);
            % save utils
            app.fs_SOFA = app.Obj_HRTF.Data.SamplingRate;
            app.N = size(app.Obj_HRTF.Data.IR,3);
            ir = shiftdim(app.Obj_HRTF.Data.IR, 2);   
%             app.Obj_HRTF.Data.IR = ir./max(abs(ir(:)))*0.975;   
            app.Obj_HRTF.Data.IR = ir;
            app.dspHRIR_L = app.Obj_HRTF.Data.IR(:,:,1).';
            app.dspHRIR_R = app.Obj_HRTF.Data.IR(:,:,2).';
            
            % General flags
            if app.no_input >= 1 % Verifica se audio já foi inserido
                app.PlayButton.Enable = true;                   
                app.PauseButton.Enable = true;                
            end 
            app.ROI_flag = 1;
            delete(app.roi_listen);
            app.flag_airabs_eval = true;
            app.flag_DistChanged = 1;
            
            % Check air absoption
            if app.AirabsorptionCheckBox.Value                
                AirabsorptionCheckBoxValueChanged(app, 1);                
            end

            % Set plot properties
            color = [0.5, 0.47, 0.53];
            app.props = {'LineStyle','none','Marker','o','MarkerEdge',color,'MarkerSize',5, ...
                         'MarkerFaceColor', color};
            
         
        end

        % Value changed function: AudioWAVButton
        function AudioWAVButtonValueChanged(app, event)
            frameLength = app.BufferSize.Value; %make this a user defined option
            if app.startUP
                app.NameAudio = 'Voice Sabine Short_edited.wav'; 
                [aud, app.fs_Audio] = audioread(app.NameAudio);
%                 aud = aud./max(abs(aud(:)))*0.975;
                aud = aud*((10^(-8/20))/(max(abs(aud(:)))));
                app.Audio = dsp.SignalSource(aud, frameLength); 
            else
                [NameAud, Audfolder] = uigetfile([pwd, filesep, '*.wav'], 'Load Audio File:');
                app.NameAudio = NameAud; %nomeie fora da função, caso o usuario feche a janela sem selecionar arquivo
                app.Audiofolder = Audfolder;
                figure(app.UIFigure) %keep focus on the app window
                [aud, app.fs_Audio] = audioread(fullfile(app.Audiofolder, app.NameAudio));
%                 aud = aud./max(abs(aud(:)))*0.975;
                aud = aud*((10^(-8/20))/(max(abs(aud(:)))));
                app.Audio = dsp.SignalSource(aud, frameLength); 
            end           
            app.AUDIOpath.Value = app.NameAudio;   
            app.deviceWriter = audioDeviceWriter( ...
                                    "SampleRate", app.fs_Audio,...
                                    "BitDepth","16-bit integer"); 
            app.no_input = size(app.Audio(), 2);   
            app.ROI_flag = 1;
            
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            app.PlayButton.Value = 0;
            app.PlayButton.Enable = false;
            app.HRTFSOFAButton.Enable = false;
            app.AudioWAVButton.Enable = false;
            app.HpTFWAVButton.Enable = false;
            app.BinauralRenderButton.Enable = false;
            app.XButton.Enable = false;
            app.PauseButton.Value = 0;
            allocateFIRfilter(app)
            
            %%% PLAY 
            % only allow auralize if Sample Rate matchs
            if app.fs_Audio ~= app.fs_SOFA 
                warndlg(['TAXA DE AMOSTRAGEM: HRTFs estão em ', ...
                    num2str(app.fs_SOFA/1000) 'kHz, mas Audio está em ', ...
                    num2str(app.fs_Audio/1000) 'kHz'], 'Erro');
                app.BinauralRenderButton.Enable = true;
                app.PlayButton.Enable = true;
                app.PauseButton.Enable = true;
                app.HRTFSOFAButton.Enable = true;
                app.AudioWAVButton.Enable = true;
                app.HpTFWAVButton.Enable = true;   
                app.XButton.Enable = true;
            else  
                % Verificar se HeadTracker esta aberto ou foi fechado
                check_HT_status(app)
                if app.ROI_flag % caso precise redesenhar posicoes, desenhe
                    switch app.ViewType.Value 
                        case 'Top'
                            draw_top_view(app)                    
                        otherwise                    
                            draw_front_view(app)
                    end
                end
                
                %%%% Fazer convolução HpTF 
                if app.flag_opt_IR && ~app.conv_done
                   optional_IR_conv(app) 
                end                                          
        
                % Play Obj_HRTFect setup
                release(app.Audio)
                release(app.deviceWriter)
                app.BufferSize.Value = 2^nextpow2(app.BufferSize.Value);
                app.Audio.SamplesPerFrame = app.BufferSize.Value;
                setup(app.deviceWriter,zeros(app.BufferSize.Value, 2))
                out_temp_L = zeros(app.BufferSize.Value,app.no_input);
                out_temp_R = zeros(app.BufferSize.Value,app.no_input);
                yaw = 0;
                pitch = 0;
                pos = app.posi(:,1:2);
                idx_changer = inf;
                release(app.Audio)                         
                release(app.deviceWriter)  
        
        %% ############## PLAY AND PROCESS AUDIO ##############################################################    
                
                while ~isDone(app.Audio)   
                    drawnow('limitrate')   % permite a movimentação do cursor
                %     pause(0)
                    % Check pause button action
                    if app.PauseButton.Value      
                        break
                    end                    
                    audioIn = app.Audio();                     
                    % Apply HRTFs
                    if app.flag_HeadTracker
                        py_output = app.udpr();
                        if ~isempty(py_output)                          
                            data = str2double(split(convertCharsToStrings(char(py_output)), ','));
                            yaw = data(1);
                            pitch = data(2);
                        end
                    end                    
                    for k = 1:app.no_input
                        name = app.ROI_list{k};                                          
                        if app.flag_HeadTracker  % Calibrate for Head Tracker position
                            idx_posL = dsearchn(pos, ...
                                               (pos(app.idx_FIR.(name)(1),:) - [yaw, pitch])); 
                            idx_posR = dsearchn(pos, ...
                                               (pos(app.idx_FIR.(name)(2),:) - [yaw, pitch]));
                        else
                            idx_posL = app.idx_FIR.(name)(1);
                            idx_posR = app.idx_FIR.(name)(2);
                        end   
        
                        % HRTFs     
                        if idx_changer ~= idx_posL % if position is the same no need to update numerator
                            app.FIR{k}.Numerator = app.dspHRIR_L(idx_posL,:);
                            app.FIR{k+app.no_input}.Numerator = app.dspHRIR_R(idx_posR,:);
                        end
                        out_temp_L(:,k) = app.FIR{k}(audioIn(:,k)); % Left
                        if ~app.flag_opt_IR 
                            out_temp_R(:,k) = app.FIR{k+app.no_input}(audioIn(:,k)); % Right  
                        else % Caso IR opcional inserida                                     
                            out_temp_R(:,k) = app.FIR{k+app.no_input}(audioIn(:,k+app.no_input)); % Right
                            % Acima: o termo (+app.no_input) foi adicionado
                            % pq cada canal do audioIn foi convoluido com
                            % os dois canais da RI inserida
                        end   
        
                        % Apply HRTF eq for distance variation (if required)
                        method = app.DistancevariationmethodListBox.Value;
                        if strcmpi(method, 'HRTF equalization')
                            out_temp_L(:,k) = app.dist_FIR.(name){1}(out_temp_L(:,k));
                            out_temp_R(:,k) = app.dist_FIR.(name){2}(out_temp_R(:,k));    
                        elseif strcmpi(method, 'Inverse Law') 
                            out_temp_L(:,k) = out_temp_L(:,k).*app.DistNorm(k,1);
                            out_temp_R(:,k) = out_temp_R(:,k).*app.DistNorm(k,2);
                        end                        
                    end
                    idx_changer = idx_posL;
        
                    %% %% OUTPUT %%%%% 
                    y = [mean(out_temp_L,2), mean(out_temp_R,2)];
%                     method = app.DistancevariationmethodListBox.Value;
%                     if strcmpi(method, 'Inverse Law') || app.DistValue > app.posi(1,3)
%                            y = y.*app.DistNorm;
%                     end
                    app.deviceWriter(y); 
                end
            end
            
            %%% after it's done
            release(app.Audio)                         
            release(app.deviceWriter)  
            app.PlayButton.Enable = true;
            app.HRTFSOFAButton.Enable = true;
            app.AudioWAVButton.Enable = true;
            app.HpTFWAVButton.Enable = true;
            app.BinauralRenderButton.Enable = true; 
            if app.flag_opt_IR
               app.XButton.Enable = true;
            end          
            for k =1:app.no_input*2
                release(app.FIR{k});
            end
                       
        end

        % Value changed function: HpTFWAVButton
        function HpTFWAVButtonValueChanged(app, event)
            [IR_name, folder] = uigetfile([pwd, filesep, '*.wav'], 'Load Impulse Response:');
            figure(app.UIFigure) %keep focus on the app window
            [temp_ir, app.fs_opt_IR] = audioread(fullfile(folder, IR_name));
            % Suporte para no máximo dois canais
            if size(temp_ir, 2) > 2
                 warndlg('Número de canais não suportado, máximo 2 canais', 'Erro');
            else
                app.opt_IR = (temp_ir./max(abs(temp_ir(:))))*0.975;
                if size(app.opt_IR, 2) < 2 % if mono make it dual channel                        
                    app.opt_IR(:,2) = app.opt_IR(:,1);                                                                                                                             
                end
                app.opt_IRpath.Value = IR_name;
                app.flag_opt_IR = 1;
%                 app.ROI_flag = 1;                
                app.XButton.Enable = true;              
            end          
        end

        % Value changed function: BinauralRenderButton
        function BinauralRenderButtonValueChanged(app, event)
            release(app.Audio)                         
            release(app.deviceWriter)   
            app.BinauralRenderButton.Enable = false;
            app.PlayButton.Enable = false;
            app.PauseButton.Enable = false;
            app.HRTFSOFAButton.Enable = false;
            app.AudioWAVButton.Enable = false;
            app.HpTFWAVButton.Enable = false;
            app.XButton.Enable = false;
            
            wait = waitbar(0, 'Processing output...', 'Name','Rendering:');           
            if ~app.flag_opt_IR % Load audio again
                [aud, ~] = audioread(fullfile(app.Audiofolder, app.NameAudio));
            else
                optional_IR_conv(app) % make sure IR is convoluted
            end
            
            for k = 1:app.no_input    
                waitbar(k/app.no_input, wait)
                name = app.ROI_list{k};  
                            idx_posL = app.idx_FIR.(name)(1);
                            idx_posR = app.idx_FIR.(name)(2);
                % Convolução saída        
                if app.flag_opt_IR % Caso IR opcional inserida                   
                    nfft = length(app.convAudio(:,k)) + length(app.dspHRIR_L(idx_posL,:)) - 1;
                    out_render_L(:,k)=real(ifft(fft(app.convAudio(:,k), nfft).* ...
                                         fft(app.dspHRIR_L(idx_posL,:).', nfft), nfft)); % Left
                    out_render_R(:,k)=real(ifft(fft(app.convAudio(:,k+app.no_input), nfft).*...
                                         fft(app.dspHRIR_R(idx_posR,:).', nfft), nfft)); % Right
                else
                    nfft = length(aud(:,k)) + length(app.dspHRIR_L(idx_posL,:)) - 1;
                    out_render_L(:,k) = ifft(fft(aud(:,k),nfft).* fft(app.dspHRIR_L(idx_posL,:).',nfft),nfft); % Left
                    out_render_R(:,k) = ifft(fft(aud(:,k),nfft).* fft(app.dspHRIR_R(idx_posR,:).',nfft),nfft); % Right
                end%app.dspHRIR_R(idx_posR,:)
                waitbar((k/app.no_input + k/(app.no_input*2)), wait)
            end 
                      
            y = [mean(out_render_L,2) mean(out_render_R,2)];  
            y = y./max(abs(y(:)))*0.975;
            % Ask for save path and name    
            try
                [~, baseFileNameNoExt, ~] = fileparts([app.Audiofolder  app.NameAudio ]);
                [file,path,~] = uiputfile([baseFileNameNoExt, '_EAC.wav']);                
                audiowrite([path file], y, app.fs_Audio)
            catch
            end
            figure(app.UIFigure) %keep focus on the app window
            waitbar(1, wait); pause(1); close(wait)
            
            app.BinauralRenderButton.Enable = true;
            app.PlayButton.Enable = true;
            app.PauseButton.Enable = true;
            app.HRTFSOFAButton.Enable = true;
            app.AudioWAVButton.Enable = true;
            app.HpTFWAVButton.Enable = true;   
            app.XButton.Enable = true;
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.fs_opt_IR = [];
            app.opt_IR = [];
            app.opt_IRpath.Value = '';
            app.flag_opt_IR = 0;
            app.conv_done = false;
            % Remover convolução
            release(app.Audio)                         
            release(app.deviceWriter)
            [aud, ~] = audioread(fullfile(app.Audiofolder, app.NameAudio));
            app.Audio = dsp.SignalSource(aud,app.BufferSize.Value); 
        end

        % Value changing function: DistanceSlider
        function DistanceSliderValueChanging(app, event)
            app.DistValue = event.Value;
            app.flag_DistChanged = 1;            
            if ~app.ROI_flag
                get_parallax_pos(app)  % recalcular parallax
                ml_version = verLessThan('matlab','9.9'); % if matlab >= R2020b
                for k=1:app.no_input              
                    HRIR_filter_design(app, app.ROI.(app.ROI_list{k}).Position(1),...
                                      app.ROI.(app.ROI_list{k}).Position(2),...
                                      app.ROI.(app.ROI_list{k}).Tag)
                    % Marker size
                    if ~ml_version
                        app.ROI.(app.ROI_list{k}).MarkerSize = app.MarkerSize; 
                    end
                end               
            end
            app.flag_DistChanged = 0;
        end

        % Value changed function: AirabsorptionCheckBox
        function AirabsorptionCheckBoxValueChanged(app, event)
        
            % Calcula absorção somente se for atualizado as HRTFs
            if app.flag_airabs_eval % atualizar valor da absorcao
                freq = linspace(0, app.fs_SOFA-app.fs_SOFA/app.N, app.N);
                app.air_abs = 0;
                for k = 1:length(freq)
                    [~, app.air_abs(k,1), ~, ~] = air_absorption(freq(k),...
                                                            app.airTemperature,...
                                                            app.airHumidity,...
                                                            app.airPressure);                    
                end
                % generate filter                   
                app.norm_freq = freq(1:app.N/2)./max(freq(1:app.N/2)); % frequency vector (half)             
                app.abs_dist_vec = app.headradius:0.2:5; % pre-initialized positions
                N_air_pos = length(app.abs_dist_vec);
                app.AirAbsFilter = cell(N_air_pos, 1);
                for k = 1:N_air_pos
                    alpha = 10.^(  -app.air_abs(1:app.N/2,1).*app.abs_dist_vec(k)  ./20);% linear magitude               
                    d = fdesign.arbmag('N,F,A',50, app.norm_freq,alpha); 
                    app.AirAbsFilter{k} = design(d,'freqsamp','SystemObject',true); 
                end
                app.flag_airabs_eval = false;
                
            end
            
            if app.AirabsorptionCheckBox.Value % se absorção do ar for ativada
                if ~app.ROI_flag % se tiver posições pra calcular no grid: calcule                    
                    if app.flag_HeadTracker % se HT ativado precisa atualizar o grid inteiro   
                        % confirma se HT realmente ativo (dentro do if pra evitar overhead caso ja nao esteja ligado mesmo)
                        check_HT_status(app) 
                        idx_air_abs = dsearchn(app.abs_dist_vec', app.DistValue);
                        air_filter = app.AirAbsFilter{idx_air_abs};
                        for ii=1:length(app.posi)                       
                            app.dspHRIR_L(ii,:) = air_filter(app.Obj_HRTF.Data.IR(:,ii,1));
                            app.dspHRIR_R(ii,:) = air_filter(app.Obj_HRTF.Data.IR(:,ii,2));
                        end
                    else % sem head tracker
                        for k=1:app.no_input % atualiza apenas posição atual            
                             HRIR_filter_design(app, app.ROI.(app.ROI_list{k}).Position(1),...
                                                app.ROI.(app.ROI_list{k}).Position(2),...
                                                app.ROI.(app.ROI_list{k}).Tag)             
                        end 
                    end
                end
            else % se desativada: reset it all 
                app.dspHRIR_L = app.Obj_HRTF.Data.IR(:,:,1).';
                app.dspHRIR_R = app.Obj_HRTF.Data.IR(:,:,2).';
            end
        end

        % Button pushed function: CAMHeadTrackerButton
        function CAMHeadTrackerButtonPushed(app, event)
            try
                open(which('Headtracker.exe', 'first'));               
            catch
                warndlg('Não foi possivel abrir ou encontrar HeadTracker.exe', 'Erro');
            end         
            check_HT_status(app)
        end

        % Value changed function: airTemperature
        function airTemperatureValueChanged(app, event)
            value = app.airTemperature.Value;
            if app.AirabsorptionCheckBox.Value
                AirabsorptionCheckBoxValueChanged(app, value);
            end
        end

        % Value changed function: airHumidity
        function airHumidityValueChanged(app, event)
            value = app.airHumidity.Value;
            if app.AirabsorptionCheckBox.Value
                app.flag_airabs_eval = true;
                AirabsorptionCheckBoxValueChanged(app, value);
            end
        end

        % Value changed function: airPressure
        function airPressureValueChanged(app, event)
            value = app.airPressure.Value;
            if app.AirabsorptionCheckBox.Value
                app.flag_airabs_eval = true;
                AirabsorptionCheckBoxValueChanged(app, value);
            end
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            app.PlayButton.Value = 1;
            app.PlayButton.Enable = true;
        end

        % Value changed function: DistancevariationmethodListBox
        function DistancevariationmethodListBoxValueChanged(app, event)
            if ~app.ROI_flag
                for k=1:app.no_input
                    pos = app.ROI.(app.ROI_list{k}).Position;
                    if strcmpi(app.ViewType.Value, 'Top')
                        app.flag_DistChanged = true;
                        [azi,ele,~] = top2cart_coords(app, pos(1), pos(2));
                    else
                        azi = pos(1);
                        ele = pos(2);
                    end
                    
                    HRIR_filter_design(app, azi, ele,...
                                      app.ROI.(app.ROI_list{k}).Tag)
                end               
            end
            
        end

        % Value changed function: ViewType
        function ViewTypeValueChanged(app, event)
            value = app.ViewType.Value;  
            if ~app.ROI_flag
                delete(app.roi_listen);
                app.ROI_flag = 1;
                for k=1:app.no_input  
                    name = app.ROI_list{k};
                    x_coord = app.ROI.(name).Position(1);
                    y_coord = app.ROI.(name).Position(2);
                    
                    % top 2 frontal
                    if strcmpi(value, 'Frontal') 
                         [azimm(k),~,~]=cart2sph(x_coord, y_coord, app.radius.(name));                    
                         azimm(k) = rad2deg(azimm(k));
                         elevv(k) = 0;
                    % frontal 2 top     
                    else             
                        azimm(k) = x_coord;
                        elevv(k) = y_coord;
                        app.radius.(name) = app.DistValue;
                    end
                end               
                
                switch value
                    case 'Top'
                        app.DistanceSlider.Enable = false;
                        draw_top_view(app, azimm, elevv)                        
                    otherwise
                        app.DistanceSlider.Enable = true;
                        draw_front_view(app, azimm, elevv)                       
                end
                % Lazy shortcut to recalculate the actual positions
                DistancevariationmethodListBoxValueChanged(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UIAxes = uiaxes(app.MainTab);
            app.UIAxes.XLim = [-180 180];
            app.UIAxes.YLim = [-90 90];
            app.UIAxes.XTick = [-180 -160 -140 -120 -100 -80 -60 -40 -20 0 20 40 60 80 100 120 140 160 180];
            app.UIAxes.XTickLabel = {'-180'; '-160'; '-140'; '-120'; '-100'; '-80'; '-60'; '-40'; '-20'; '0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'};
            app.UIAxes.YTick = [-90 -60 -30 0 30 60 90];
            app.UIAxes.YTickLabel = {'-90'; '-60'; '-30'; '0'; '30'; '60'; '90'};
            app.InputsPanel = uipanel(app.MainTab);
            app.InputsPanel.Title = 'Inputs';
            app.SOFApath = uieditfield(app.InputsPanel, 'text');
            app.SOFApath.Editable = 'off';
            app.AUDIOpath = uieditfield(app.InputsPanel, 'text');
            app.AUDIOpath.Editable = 'off';
            app.HRTFSOFAButton = uibutton(app.InputsPanel, 'state');
            app.HRTFSOFAButton.ValueChangedFcn = createCallbackFcn(app, @HRTFSOFAButtonValueChanged, true);
            app.HRTFSOFAButton.Text = 'HRTF (.SOFA)';
            app.AudioWAVButton = uibutton(app.InputsPanel, 'state');
            app.AudioWAVButton.ValueChangedFcn = createCallbackFcn(app, @AudioWAVButtonValueChanged, true);
            app.AudioWAVButton.Text = 'Audio (.WAV)';
            app.OptionalInputsPanel = uipanel(app.MainTab);
            app.OptionalInputsPanel.Title = 'Optional Inputs';
            app.opt_IRpath = uieditfield(app.OptionalInputsPanel, 'text');
            app.opt_IRpath.Editable = 'off';
            app.HpTFWAVButton = uibutton(app.OptionalInputsPanel, 'state');
            app.HpTFWAVButton.ValueChangedFcn = createCallbackFcn(app, @HpTFWAVButtonValueChanged, true);
            app.HpTFWAVButton.Text = 'HpTF (.WAV)';
            app.XButton = uibutton(app.OptionalInputsPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Enable = 'off';
            app.XButton.Text = 'X';
            app.DIstancemLabel = uilabel(app.MainTab);
            app.DIstancemLabel.Text = {'DIstance'; '(m)'};
            app.DistanceSlider = uislider(app.MainTab);
            app.DistanceSlider.Limits = [0.2 5];
            app.DistanceSlider.MajorTicks = [0.25 1.25 2.25 3.25 4.25];
            app.DistanceSlider.ValueChangingFcn = createCallbackFcn(app, @DistanceSliderValueChanging, true);
            app.DistanceSlider.MinorTicks = [];
            app.DistanceSlider.Value = 2.5;
            app.AirabsorptionCheckBox = uicheckbox(app.MainTab);
            app.AirabsorptionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AirabsorptionCheckBoxValueChanged, true);
            app.AirabsorptionCheckBox.Text = 'Air absorption';
            app.BinauralRenderButton = uibutton(app.MainTab, 'state');
            app.BinauralRenderButton.ValueChangedFcn = createCallbackFcn(app, @BinauralRenderButtonValueChanged, true);
            app.BinauralRenderButton.Enable = 'off';
            app.BinauralRenderButton.Text = 'Binaural Render';
            app.PlayButton = uibutton(app.MainTab, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Icon = 'playbuttom.png';
            app.PlayButton.Text = {''; ''};
            app.PauseButton = uibutton(app.MainTab, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Icon = 'stopbuttom.png';
            app.PauseButton.Text = '';
            app.InterpolationDropDownLabel = uilabel(app.MainTab);
            app.InterpolationDropDownLabel.Text = 'Interpolation:';
            app.InterpolationDropDown = uidropdown(app.MainTab);
            app.InterpolationDropDown.Items = {'Nearest'};
            app.InterpolationDropDown.Value = 'Nearest';
            app.BufferSizeLabel = uilabel(app.MainTab);
            app.BufferSizeLabel.Text = 'Buffer Size';
            app.BufferSize = uieditfield(app.MainTab, 'numeric');
            app.BufferSize.Value = 512;
            app.CAMHeadTrackerButton = uibutton(app.MainTab, 'push');
            app.CAMHeadTrackerButton.ButtonPushedFcn = createCallbackFcn(app, @CAMHeadTrackerButtonPushed, true);
            app.CAMHeadTrackerButton.Text = {'CAM '; 'Head Tracker'};
            app.ViewType = uiswitch(app.MainTab, 'slider');
            app.ViewType.Items = {'Frontal', 'Top'};
            app.ViewType.ValueChangedFcn = createCallbackFcn(app, @ViewTypeValueChanged, true);
            app.ViewType.Value = 'Frontal';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.AirabsorptionpropertiesISO96131Panel = uipanel(app.AdvancedTab);
            app.AirabsorptionpropertiesISO96131Panel.Title = 'Air absorption properties (ISO 9613-1)';
            app.TemperatureCEditFieldLabel = uilabel(app.AirabsorptionpropertiesISO96131Panel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C°)';
            app.airTemperature = uieditfield(app.AirabsorptionpropertiesISO96131Panel, 'numeric');
            app.airTemperature.ValueChangedFcn = createCallbackFcn(app, @airTemperatureValueChanged, true);
            app.airTemperature.Value = 20;
            app.HumidityLabel = uilabel(app.AirabsorptionpropertiesISO96131Panel);
            app.HumidityLabel.Text = 'Humidity (%) ';
            app.airHumidity = uieditfield(app.AirabsorptionpropertiesISO96131Panel, 'numeric');
            app.airHumidity.ValueChangedFcn = createCallbackFcn(app, @airHumidityValueChanged, true);
            app.airHumidity.Value = 80;
            app.AtmosphericpressureratioCurrentStandadEditFieldLabel = uilabel(app.AirabsorptionpropertiesISO96131Panel);
            app.AtmosphericpressureratioCurrentStandadEditFieldLabel.Text = {'Atmospheric pressure ratio'; '(Current / Standad) '};
            app.airPressure = uieditfield(app.AirabsorptionpropertiesISO96131Panel, 'numeric');
            app.airPressure.ValueChangedFcn = createCallbackFcn(app, @airPressureValueChanged, true);
            app.airPressure.Value = 1;
            app.DistancevariationmethodListBoxLabel = uilabel(app.AdvancedTab);
            app.DistancevariationmethodListBoxLabel.Text = 'Distance variation method';
            app.DistancevariationmethodListBox = uilistbox(app.AdvancedTab);
            app.DistancevariationmethodListBox.Items = {'Inverse Law', 'HRTF equalization'};
            app.DistancevariationmethodListBox.ValueChangedFcn = createCallbackFcn(app, @DistancevariationmethodListBoxValueChanged, true);
            app.DistancevariationmethodListBox.Value = 'Inverse Law';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.DaviRochaCarvalhoEngAcsticaUFSMLabel = uilabel(app.HelpTab);
            app.DaviRochaCarvalhoEngAcsticaUFSMLabel.Text = 'Davi Rocha Carvalho - Eng. Acústica UFSM .';
            app.davicarvalhoeacufsmbrLabel = uilabel(app.HelpTab);
            app.davicarvalhoeacufsmbrLabel.Text = 'davi.carvalho@eac.ufsm.br';
            app.ContactLabel = uilabel(app.HelpTab);
            app.ContactLabel.Text = 'Contact:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Auraliza

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end